classdef OceanFluxBudget < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        BalancemassButton            matlab.ui.control.StateButton
        ReinitializeButton           matlab.ui.control.Button
        ReferenceSalinitygkgSlider   matlab.ui.control.Slider
        ReferenceSalinitygkgSliderLabel  matlab.ui.control.Label
        ReferenceTemperatureCSlider  matlab.ui.control.Slider
        ReferenceTemperatureCSliderLabel  matlab.ui.control.Label
        CenterPanel                  matlab.ui.container.Panel
        GatewayStraitDropDown        matlab.ui.control.DropDown
        GatewayStraitDropDownLabel   matlab.ui.control.Label
        LFCFluxFigure                matlab.ui.control.UIAxes
        HeatFluxFigure               matlab.ui.control.UIAxes
        SaltFluxFigure               matlab.ui.control.UIAxes
        VolFluxFigure                matlab.ui.control.UIAxes
        MassFluxFigure               matlab.ui.control.UIAxes
        DensityFigure                matlab.ui.control.UIAxes
        SalinityFigure               matlab.ui.control.UIAxes
        TemperatureFigure            matlab.ui.control.UIAxes
        SpeedFigure                  matlab.ui.control.UIAxes
        RightPanel                   matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = public)
        straits     % Structure containing the straits structure.
        budgets     % Structure containing the budgets structure.
        DataParams  % Structure containing the DataParams structure.
        FluxParams  % Structure containing the FluxParams structure.
        this_strait % Name of strait being displayed.
    end

    methods (Access = private)
        function UpdateStraitsFigure(app)
            % Plot this strait's raw timeseries
            plot(app.SpeedFigure,      app.straits.(app.this_strait).time,app.straits.(app.this_strait).normal_speed) ;
            plot(app.TemperatureFigure,app.straits.(app.this_strait).time,app.straits.(app.this_strait).temperature) ;
            plot(app.SalinityFigure,   app.straits.(app.this_strait).time,app.straits.(app.this_strait).salinity) ;
            plot(app.DensityFigure,    app.straits.(app.this_strait).time,app.straits.(app.this_strait).density) ;

            % Plot this strait's computed timeseries
            plot(app.MassFluxFigure,app.straits.(app.this_strait).time,app.straits.(app.this_strait).mass_flux) ;
            plot(app.VolFluxFigure, app.straits.(app.this_strait).time,app.straits.(app.this_strait).vol_flux./1e6) ;
            plot(app.SaltFluxFigure,app.straits.(app.this_strait).time,app.straits.(app.this_strait).salt_flux) ;
            plot(app.HeatFluxFigure,app.straits.(app.this_strait).time,app.straits.(app.this_strait).heat_flux) ;
            plot(app.LFCFluxFigure, app.straits.(app.this_strait).time,app.straits.(app.this_strait).LFC_flux) ;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [app.straits, app.DataParams, app.FluxParams] = InitializeStraits(false) ;
            app.straits = UpdateStraits(app.FluxParams, app.straits) ;
            app.budgets = ComputeBudgets(app.straits) ;
            app.this_strait = 'FramStrait' ;
            UpdateStraitsFigure(app) ;
        end

        % Value changed function: ReferenceTemperatureCSlider
        function ReferenceTemperatureCSliderValueChanged(app, event)
            app.FluxParams.T_ref = app.ReferenceTemperatureCSlider.Value ;
            app.straits = UpdateStraits(app.FluxParams, app.straits) ;
            app.budgets = ComputeBudgets(app.straits) ;
            UpdateStraitsFigure(app) ;
        end

        % Value changed function: ReferenceSalinitygkgSlider
        function ReferenceSalinitygkgSliderValueChanged(app, event)
            app.FluxParams.S_ref = app.ReferenceSalinitygkgSlider.Value;
            app.straits = UpdateStraits(app.FluxParams, app.straits) ;
            app.budgets = ComputeBudgets(app.straits) ;
            UpdateStraitsFigure(app) ;
        end

        % Button pushed function: ReinitializeButton
        function ReinitializeButtonPushed(app, event)
            [app.straits, app.DataParams, app.FluxParams] = InitializeStraits(app.DataParams.massBalance) ;
            app.straits = UpdateStraits(app.FluxParams, app.straits) ;
            app.budgets = ComputeBudgets(app.straits) ;
            UpdateStraitsFigure(app) ;
        end

        % Value changed function: BalancemassButton
        function BalancemassButtonValueChanged(app, event)
            app.DataParams.massBalance = app.BalancemassButton.Value;
        end

        % Value changed function: GatewayStraitDropDown
        function GatewayStraitDropDownValueChanged(app, event)
            app.this_strait = app.GatewayStraitDropDown.Value;
            UpdateStraitsFigure(app) ;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {943, 943, 943};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {943, 943};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {422, '1x', 375};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ReferenceTemperatureCSliderLabel = uilabel(app.LeftPanel);
            app.ReferenceTemperatureCSliderLabel.Text = 'Reference Temperature [C]';
            app.ReferenceTemperatureCSlider = uislider(app.LeftPanel);
            app.ReferenceTemperatureCSlider.Limits = [-2 40];
            app.ReferenceTemperatureCSlider.ValueChangedFcn = createCallbackFcn(app, @ReferenceTemperatureCSliderValueChanged, true);
            app.ReferenceSalinitygkgSliderLabel = uilabel(app.LeftPanel);
            app.ReferenceSalinitygkgSliderLabel.Text = 'Reference Salinity [g/kg]';
            app.ReferenceSalinitygkgSlider = uislider(app.LeftPanel);
            app.ReferenceSalinitygkgSlider.Limits = [0 40];
            app.ReferenceSalinitygkgSlider.ValueChangedFcn = createCallbackFcn(app, @ReferenceSalinitygkgSliderValueChanged, true);
            app.ReferenceSalinitygkgSlider.Value = 34.8;
            app.ReinitializeButton = uibutton(app.LeftPanel, 'push');
            app.ReinitializeButton.ButtonPushedFcn = createCallbackFcn(app, @ReinitializeButtonPushed, true);
            app.ReinitializeButton.Text = 'Reinitialize';
            app.BalancemassButton = uibutton(app.LeftPanel, 'state');
            app.BalancemassButton.ValueChangedFcn = createCallbackFcn(app, @BalancemassButtonValueChanged, true);
            app.BalancemassButton.Text = 'Balance mass ';
            app.BalancemassButton.Value = true;
            app.CenterPanel = uipanel(app.GridLayout);
            app.SpeedFigure = uiaxes(app.CenterPanel);
            title(app.SpeedFigure, 'Speed [m/s]')
            ylabel(app.SpeedFigure, 'Speed [m/s]')
            zlabel(app.SpeedFigure, 'Z')
            app.TemperatureFigure = uiaxes(app.CenterPanel);
            title(app.TemperatureFigure, 'Temperature [C]')
            ylabel(app.TemperatureFigure, 'Temperature [C]')
            zlabel(app.TemperatureFigure, 'Z')
            app.SalinityFigure = uiaxes(app.CenterPanel);
            title(app.SalinityFigure, 'Salinity [g/kg]')
            ylabel(app.SalinityFigure, 'Salinity [g/kg]')
            zlabel(app.SalinityFigure, 'Z')
            app.DensityFigure = uiaxes(app.CenterPanel);
            title(app.DensityFigure, 'Density [kg/m^3]')
            xlabel(app.DensityFigure, 'Time')
            ylabel(app.DensityFigure, 'Density [kg/m^3]')
            zlabel(app.DensityFigure, 'Z')
            app.MassFluxFigure = uiaxes(app.CenterPanel);
            title(app.MassFluxFigure, 'Mass flux [kg/s]')
            ylabel(app.MassFluxFigure, 'Mass flux [kg/s]')
            zlabel(app.MassFluxFigure, 'Z')
            app.VolFluxFigure = uiaxes(app.CenterPanel);
            title(app.VolFluxFigure, 'Volume flux [Sv]')
            ylabel(app.VolFluxFigure, 'Volume flux [Sv]')
            zlabel(app.VolFluxFigure, 'Z')
            app.SaltFluxFigure = uiaxes(app.CenterPanel);
            title(app.SaltFluxFigure, 'Salt flux [kg/s]')
            ylabel(app.SaltFluxFigure, 'Salt flux [kg/s]')
            zlabel(app.SaltFluxFigure, 'Z')
            app.HeatFluxFigure = uiaxes(app.CenterPanel);
            title(app.HeatFluxFigure, 'Liquid FW flux [km^3/yr]')
            xlabel(app.HeatFluxFigure, 'Time')
            ylabel(app.HeatFluxFigure, 'Liquid FW flux [km^3/yr]')
            zlabel(app.HeatFluxFigure, 'Z')
            app.LFCFluxFigure = uiaxes(app.CenterPanel);
            title(app.LFCFluxFigure, 'Heat flux [PW]')
            ylabel(app.LFCFluxFigure, 'Heat flux [PW]')
            zlabel(app.LFCFluxFigure, 'Z')
            app.GatewayStraitDropDownLabel = uilabel(app.CenterPanel);
            app.GatewayStraitDropDownLabel.Text = 'Gateway Strait';
            app.GatewayStraitDropDown = uidropdown(app.CenterPanel);
            app.GatewayStraitDropDown.Items = {'DavisStrait', 'FramStrait'};
            app.GatewayStraitDropDown.ItemsData = {'Fram Strait,Davis Strait'};
            app.GatewayStraitDropDown.ValueChangedFcn = createCallbackFcn(app, @GatewayStraitDropDownValueChanged, true);
            app.GatewayStraitDropDown.Value = 'Fram Strait,Davis Strait';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OceanFluxBudget

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end